classdef Sterowanie < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Kana1Panel             matlab.ui.container.Panel
        ButtonGroup            matlab.ui.container.ButtonGroup
        PredkoscButton         matlab.ui.control.RadioButton
        PrzemieszczenieButton  matlab.ui.control.RadioButton
        PrzyspieszenieLabel    matlab.ui.control.Label
        Acc                    matlab.ui.control.NumericEditField
        Parameter1             matlab.ui.control.NumericEditField
        SterujButton           matlab.ui.control.Button
        Parameter1Label        matlab.ui.control.Label
        StopButton             matlab.ui.control.Button
        Kana2Panel             matlab.ui.container.Panel
        ButtonGroup2           matlab.ui.container.ButtonGroup
        PredkoscButton2        matlab.ui.control.RadioButton
        PozycjaButton2         matlab.ui.control.RadioButton
        Przyspieszenie2Label   matlab.ui.control.Label
        Acc2                   matlab.ui.control.NumericEditField
        Parameter2             matlab.ui.control.NumericEditField
        SterujButton2          matlab.ui.control.Button
        Parameter2Label        matlab.ui.control.Label
        Stop2Button            matlab.ui.control.Button
    end

    
    properties (Access = private)
        s;
    end
    
    methods (Access = private)
        
        function [Port] = PortOnBoot(app)
            Tytul = 'Port';
            Wiadomosc = 'Podaj port COM arduino, np.: COM3';
            Port = inputdlg(Wiadomosc,Tytul);
            Port = char(Port);
        end
        
        function [] = SetupSerial(app,Port)
            delete(instrfind)
            app.s = serial(Port);
            fopen(app.s);
            pause(2)
            a = 'b';
            fwrite(app.s,'a,');
            while(a ~= 'a')
                a = fread(app.s,1,'uchar');
            end
            if a == 'a'
                mbox= helpdlg('Komunikacja nawiązana');
                uiwait(mbox);
            else
                mbox = errordlg('BRAK KOMUNIKACJI','Błąd komunikacji');
                uiwait(mbox)
            end
        end
        
        function [] = SendCmd(app,Command)
            %Funkcja ma wysłać polecenie na port szeregowy.
            disp(Command);
            fwrite(app.s,char(Command));
        end
        
        function [Send] = BuildCmd(app,Input)
            %Funkcja budująca polecenie, które zostanie wysłane do Arduino, Input jest wektorem o 3 argumentach.
            Msg = 'servo:%d:%.2f:%.2f,';
            Send = compose(Msg,Input);
        end
        
        function [Data] = GatherData(app,Channel)
            %Funkcja zbiera dane z pól numerycznych, jako wejście przyjmuje kanał na który ma zostać wysłane późniejsze polecenie.
            GatherDataMatrix = [app.Parameter1.Value, app.Acc.Value;
                app.Parameter2.Value, app.Acc2.Value];                
            Data = [Channel, GatherDataMatrix(Channel+1,1), GatherDataMatrix(Channel+1,2)];
        end    


        function [Data] = GetDir(app,Channel,Pos)
            if Pos == 0
                Data = [Channel 5972 0];
            elseif Pos >0
                Data = [Channel 8000 0];
            else
                Data = [Channel 4000 0];
            end
            
        end
        
        function [] = SendVel(app,Channel)
            Data = GatherData(app,Channel);
            Data(2) = ConvVelocity(app,Data(2));
            Data(3) = ConvAcceleration(app,Data(3));
            Send = BuildCmd(app,Data);
            SendCmd(app,Send);
        end
        
        function [] = SendVelY(app,Channel)
            Data = GatherData(app,Channel);
            Data(2) = ConvVelocity(app,Data(2)*2);
            Data(3) = ConvAcceleration(app,Data(3));
            Send = BuildCmd(app,Data);
            SendCmd(app,Send);
        end
        
        function [] = SendPos(app,Channel)
            Data = GatherData(app,Channel);
            Pos = Data(3);
            Pos = ConvPosition(app,Data(2),Pos);
            Data(3) = 0;
            Data(2) = ConvVelocity(app,Data(2));
            Send = BuildCmd(app,Data);
            SendCmd(app,Send);
            disp(Pos);
            pause(Pos);
            SendStop(app,Channel);
        end
        
        function [] = SendPosY(app,Channel)
            Data = GatherData(app,Channel);
            Pos = Data(3);
            Pos = ConvPositionY(app,Data(2),Pos);
            Data(3) = 0;
            Data(2) = ConvVelocity(app,Data(2)*3);
            Send = BuildCmd(app,Data);
            SendCmd(app,Send);
            disp(Pos);
            pause(Pos);
            SendStop(app,Channel);
        end
        
        function [] = SendStop(app,Channel)
            Data = GetDir(app,Channel,0);
            Send = BuildCmd(app,Data);
            SendCmd(app,Send);
        end
        
        function [Vel] = ConvVelocity(app,VelIn)
            Vel = 6000+(VelIn/7)*2000;
        end
        
        function [Accel] = ConvAcceleration(app, AccIn)
            Accel = 255*AccIn/100;
        end
        
        function [Pos] = ConvPosition(app,VelIn,PosIn)
            Pos = PosIn/(13*VelIn);
            Pos = abs(Pos);
        end           
        
        function [Pos] = ConvPositionY(app,VelIn,PosIn)
            Pos = PosIn/(15.5*VelIn);
            Pos = abs(Pos);
        end         
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Port = PortOnBoot(app);
            SetupSerial(app,Port);
            clc;
        end

        % Selection changed function: ButtonGroup2
        function ButtonGroup2SelectionChanged(app, event)
            if app.PredkoscButton2.Value == 1
                app.Przyspieszenie2Label.Text = 'Przyspieszenie [%]';
                app.Acc2.Limits = [0 100];
                app.Acc2.Value = 0;
                app.Parameter2.Value = 0;
            else
                app.Przyspieszenie2Label.Text = 'Przemieszczenie [mm]';
                app.Acc2.Limits = [-500 500];
                app.Acc2.Value = 0;
                app.Parameter2.Value = 0;
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            if app.PredkoscButton.Value == 1
                app.PrzyspieszenieLabel.Text = 'Przyspieszenie [%]';
                app.Acc.Limits = [-100 100];
                app.Acc.Value = 0;
                app.Parameter1.Value = 0;
            else                
                app.PrzyspieszenieLabel.Text = 'Przemieszczenie [mm]';
                app.Acc.Limits = [10 1000];
                app.Acc.Value = 1000;
                app.Parameter1.Value = 0;
            end
        end

        % Button pushed function: SterujButton2
        function SterujButton2Pushed(app, event)
            if app.PredkoscButton2.Value == 1
                SendVelY(app,1);
            elseif app.PozycjaButton2.Value == 1
                SendPosY(app,1);
            end
        end

        % Button pushed function: SterujButton
        function SterujButtonPushed(app, event)
            if app.PredkoscButton.Value == 1
                SendVel(app,0);
            elseif app.PrzemieszczenieButton.Value == 1
                SendPos(app,0);
            end
        end

        % Button pushed function: Stop2Button
        function Stop2ButtonPushed(app, event)
            SendStop(app,1);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            SendStop(app,0);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
            delete(instrfind);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Kana1Panel = uipanel(app.UIFigure);
            app.Kana1Panel.Title = 'Kanał 1';
            app.ButtonGroup = uibuttongroup(app.Kana1Panel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.PredkoscButton = uiradiobutton(app.ButtonGroup);
            app.PredkoscButton.Text = 'Prędkość';
            app.PredkoscButton.Value = true;
            app.PrzemieszczenieButton = uiradiobutton(app.ButtonGroup);
            app.PrzemieszczenieButton.Text = 'Przemieszczenie';
            app.PrzyspieszenieLabel = uilabel(app.Kana1Panel);
            app.PrzyspieszenieLabel.Text = 'Przyspieszenie [%]';
            app.Acc = uieditfield(app.Kana1Panel, 'numeric');
            app.Acc.Limits = [0 100];
            app.Acc.ValueDisplayFormat = '%.2f';
            app.Parameter1 = uieditfield(app.Kana1Panel, 'numeric');
            app.Parameter1.Limits = [-7 7];
            app.Parameter1.ValueDisplayFormat = '%.2f';
            app.SterujButton = uibutton(app.Kana1Panel, 'push');
            app.SterujButton.ButtonPushedFcn = createCallbackFcn(app, @SterujButtonPushed, true);
            app.SterujButton.Text = 'Steruj X';
            app.Parameter1Label = uilabel(app.Kana1Panel);
            app.Parameter1Label.Text = 'Prędkość [m/min]';
            app.StopButton = uibutton(app.Kana1Panel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop X';
            app.Kana2Panel = uipanel(app.UIFigure);
            app.Kana2Panel.Title = 'Kanał 2';
            app.ButtonGroup2 = uibuttongroup(app.Kana2Panel);
            app.ButtonGroup2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup2SelectionChanged, true);
            app.PredkoscButton2 = uiradiobutton(app.ButtonGroup2);
            app.PredkoscButton2.Text = 'Prędkość';
            app.PredkoscButton2.Value = true;
            app.PozycjaButton2 = uiradiobutton(app.ButtonGroup2);
            app.PozycjaButton2.Text = 'Przemieszczenie';
            app.Przyspieszenie2Label = uilabel(app.Kana2Panel);
            app.Przyspieszenie2Label.Text = 'Przyspieszenie [%]';
            app.Acc2 = uieditfield(app.Kana2Panel, 'numeric');
            app.Acc2.Limits = [0 100];
            app.Acc2.ValueDisplayFormat = '%.2f';
            app.Parameter2 = uieditfield(app.Kana2Panel, 'numeric');
            app.Parameter2.Limits = [-4 4];
            app.Parameter2.ValueDisplayFormat = '%.2f';
            app.SterujButton2 = uibutton(app.Kana2Panel, 'push');
            app.SterujButton2.ButtonPushedFcn = createCallbackFcn(app, @SterujButton2Pushed, true);
            app.SterujButton2.Text = 'Steruj Y';
            app.Parameter2Label = uilabel(app.Kana2Panel);
            app.Parameter2Label.Text = 'Prędkość [m/min]';
            app.Stop2Button = uibutton(app.Kana2Panel, 'push');
            app.Stop2Button.ButtonPushedFcn = createCallbackFcn(app, @Stop2ButtonPushed, true);
            app.Stop2Button.Text = 'Stop Y';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Sterowanie

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end